classdef layerSettings_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        uipanel2       matlab.ui.container.Panel
        cancel         matlab.ui.control.Button
        ok             matlab.ui.control.Button
        layerName      matlab.ui.control.Label
        text1          matlab.ui.control.Label
        uipanel1       matlab.ui.container.Panel
        edit_text5     matlab.ui.control.Label
        edit5          matlab.ui.control.EditField
        checkbox5      matlab.ui.control.CheckBox
        edit_text4     matlab.ui.control.Label
        edit4          matlab.ui.control.EditField
        checkbox4      matlab.ui.control.CheckBox
        edit_text3     matlab.ui.control.Label
        edit_text2     matlab.ui.control.Label
        edit_text1     matlab.ui.control.Label
        edit3          matlab.ui.control.EditField
        edit2          matlab.ui.control.EditField
        edit1          matlab.ui.control.EditField
        currentTrial   matlab.ui.control.Label
        path1          matlab.ui.control.EditField
        change         matlab.ui.control.Button
        trialList      matlab.ui.control.DropDown
        checkbox1      matlab.ui.control.CheckBox
        checkbox2      matlab.ui.control.CheckBox
        checkbox3      matlab.ui.control.CheckBox
        globalSetting  matlab.ui.control.CheckBox
        lable1         matlab.ui.control.Label
    end

    
    methods (Access = private)
        function setValues(app, handles)
            %----------------Set Values------------------------%
            
            settings = getappdata(gcf, 'tmpSettings');
            k        = str2num(get(handles.currentTrial, 'String'));
            
            %global settings
            for n = 1:length(settings)
                settings(n).global = get(handles.globalSetting, 'Value');
            end
            
            %path settings
            if ~strcmp(settings(k).path1{1}, 'OFF')
                settings(k).path1{2} = get(handles.path1, 'String');
            end
            
            %box settings
            if ~strcmp(settings(k).box1{1}, 'OFF')
                settings(k).box1{2} = get(handles.checkbox1, 'Value');
            end
            
            if ~strcmp(settings(k).box2{1}, 'OFF')
                settings(k).box2{2} = get(handles.checkbox2, 'Value');
            end
            
            if ~strcmp(settings(k).box3{1}, 'OFF')
                settings(k).box3{2} = get(handles.checkbox3, 'Value');
            end
            
            if ~strcmp(settings(k).box4{1}, 'OFF')
                settings(k).box4{2} = get(handles.checkbox4, 'Value');
            end
            
            if ~strcmp(settings(k).box5{1}, 'OFF')
                settings(k).box5{2} = get(handles.checkbox5, 'Value');
            end
            
            %text settings
            if ~strcmp(settings(k).edit1{1}, 'OFF')
                settings(k).edit1{2} = get(handles.edit1, 'String');
            end
            
            if ~strcmp(settings(k).edit2{1}, 'OFF')
                settings(k).edit2{2} = get(handles.edit2, 'String');
            end
            
            if ~strcmp(settings(k).edit3{1}, 'OFF')
                settings(k).edit3{2} = get(handles.edit3, 'String');
            end
            
            if ~strcmp(settings(k).edit4{1}, 'OFF')
                settings(k).edit4{2} = get(handles.edit4, 'String');
            end
            
            if ~strcmp(settings(k).edit5{1}, 'OFF')
                settings(k).edit5{2} = get(handles.edit5, 'String');
            end
            
            setappdata(gcf, 'tmpSettings', settings);
        end
        
        function updateFigure(app, handles)
            
            lSettings = getappdata(gcf, 'tmpSettings');
            k = str2num(get(handles.currentTrial, 'String'));
            
            %set drop down list
            trialList = [];
            
            C = length(lSettings);
            for c = 1:C
                trialList{c} = ['Trial ' num2str(c)];
            
                if c == k
                    trialList{c} = strcat(trialList{c}, '*');
                end
            end
            
            set(handles.trialList, 'String', trialList);
            
            %global
            set(handles.globalSetting, 'Value', lSettings(k).global);
            
            %path settings
            if strcmp(lSettings(k).path1{1}, 'OFF')
                set(handles.lable1, 'String', ' ');
                set(handles.path1,  'Enable', 'Off');
                set(handles.change, 'Enable', 'Off');
            else
                set(handles.lable1, 'String', lSettings(k).path1{1});
                set(handles.path1,  'Enable', 'On');
                set(handles.path1,  'String', lSettings(k).path1{2});
                set(handles.change, 'Enable', 'On');
            end
            
            %box settings
            if strcmp(lSettings(k).box1{1}, 'OFF')
                set(handles.checkbox1, 'Visible', 'Off');
            else
                set(handles.checkbox1, 'Visible', 'On');
                set(handles.checkbox1, 'String', lSettings(k).box1{1});
                set(handles.checkbox1, 'Value',  lSettings(k).box1{2});
            end
            
            if strcmp(lSettings(k).box2{1}, 'OFF')
                set(handles.checkbox2, 'Visible', 'Off');
            else
                set(handles.checkbox2, 'Visible', 'On');
                set(handles.checkbox2, 'String', lSettings(k).box2{1});
                set(handles.checkbox2, 'Value',  lSettings(k).box2{2});
            end
            
            if strcmp(lSettings(k).box3{1}, 'OFF')
                set(handles.checkbox3, 'Visible', 'Off');
            else
                set(handles.checkbox3, 'Visible', 'On');
                set(handles.checkbox3, 'String', lSettings(k).box3{1});
                set(handles.checkbox3, 'Value',  lSettings(k).box3{2});
            end
            
            if strcmp(lSettings(k).box4{1}, 'OFF')
                set(handles.checkbox4, 'Visible', 'Off');
            else
                set(handles.checkbox4, 'Visible', 'On');
                set(handles.checkbox4, 'String', lSettings(k).box4{1});
                set(handles.checkbox4, 'Value',  lSettings(k).box4{2});
            end
            
            if strcmp(lSettings(k).box5{1}, 'OFF')
                set(handles.checkbox5, 'Visible', 'Off');
            else
                set(handles.checkbox5, 'Visible', 'On');
                set(handles.checkbox5, 'String', lSettings(k).box5{1});
                set(handles.checkbox5, 'Value',  lSettings(k).box5{2});
            end
            
            %text settings
            if strcmp(lSettings(k).edit1{1}, 'OFF')
                set(handles.edit1,      'Visible', 'Off');
                set(handles.edit_text1, 'Visible', 'Off');
            else
                set(handles.edit1,      'Visible', 'On');
                set(handles.edit_text1, 'String',  lSettings(k).edit1{1});
                set(handles.edit1,      'String',  lSettings(k).edit1{2});
            end
            
            if strcmp(lSettings(k).edit2{1}, 'OFF')
                set(handles.edit2,      'Visible', 'Off');
                set(handles.edit_text2, 'Visible', 'Off');
            else
                set(handles.edit2,      'Visible', 'On');
                set(handles.edit_text2, 'String',  lSettings(k).edit2{1});
                set(handles.edit2,      'String',  lSettings(k).edit2{2});
            end
            
            if strcmp(lSettings(k).edit3{1}, 'OFF')
                set(handles.edit3,      'Visible', 'Off');
                set(handles.edit_text3, 'Visible', 'Off');
            else
                set(handles.edit3,      'Visible', 'On');
                set(handles.edit_text3, 'String',  lSettings(k).edit3{1});
                set(handles.edit3,      'String',  lSettings(k).edit3{2});
            end
            
            if strcmp(lSettings(k).edit4{1}, 'OFF')
                set(handles.edit4,      'Visible', 'Off');
                set(handles.edit_text4, 'Visible', 'Off');
            else
                set(handles.edit4,      'Visible', 'On');
                set(handles.edit_text4, 'String',  lSettings(k).edit4{1});
                set(handles.edit4,      'String',  lSettings(k).edit4{2});
            end
            
            if strcmp(lSettings(k).edit5{1}, 'OFF')
                set(handles.edit5,      'Visible', 'Off');
                set(handles.edit_text5, 'Visible', 'Off');
            else
                set(handles.edit5,      'Visible', 'On');
                set(handles.edit_text5, 'String',  lSettings(k).edit5{1});
                set(handles.edit5,      'String',  lSettings(k).edit5{2});
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function settings_OpeningFcn(app, varargin)
            % --- Executes just before settings is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to settings (see VARARGIN)
            
            % Choose default command line output for settings
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes settings wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            %Get current settings
            navData     = getappdata(0, 'navData');
            chstimuli   = getappdata(0, 'chstimuli');
            index       = navData.activeStim;
            z           = navData.marker(index);
            
            layer = chstimuli(index).layers(z);
            tmpSettings = layer.settings;
            %set layer name
            set(handles.layerName, 'String', layer.name);
            
            setappdata(gcf, 'tmpSettings', tmpSettings);
            
            updateFigure(app, handles);
        end

        % Button pushed function: cancel
        function cancel_Callback(app, event)
            % --- Executes on button press in cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(gcf);
        end

        % Button pushed function: change
        function change_Callback(app, event)
            % --- Executes on button press in change.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to change (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Get current settings
            navData     = getappdata(0, 'navData');
            chstimuli   = getappdata(0, 'chstimuli');
            index       = navData.activeStim;
            z           = navData.marker(index); %chosen layer
            k     = str2num(get(handles.currentTrial, 'String'));
            layer = chstimuli(index).layers(z);
            
            if length(layer.settings(k).path1) > 2
                FilterSpec = layer.settings(k).path1{3};
                [FileName,PathName,FilterIndex] = uigetfile(FilterSpec);
            else
                [FileName,PathName,FilterIndex] = uigetfile();
            end
            
            if FileName ~= 0
                set(handles.path1, 'String', [PathName FileName]);
            end
            
            setValues(app, handles);
            updateFigure(app, handles);
        end

        % Button pushed function: ok
        function ok_Callback(app, event)
            % --- Executes on button press in ok.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ok (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %save current values
            setValues(app, handles);
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            lSettings  = getappdata(gcf, 'tmpSettings');
            
            index = navData.activeStim;
            z     = navData.marker(index);
            
            %save new settings
            chstimuli(index).layers(z).settings = lSettings;
            
            setappdata(0, 'chstimuli', chstimuli);
            close(gcf);
        end

        % Value changed function: trialList
        function trialList_Callback(app, event)
            % --- Executes on selection change in trialList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to trialList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns trialList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from trialList
            
            newTrial = get(hObject,'Value'); %index of chosen trial
            
            setValues(app, handles);
            
            set(handles.currentTrial, 'String', num2str(newTrial));
            updateFigure(app, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'settings';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Tag = 'uipanel2';
            app.uipanel1 = uipanel(app.uipanel2);
            app.uipanel1.Title = 'Trial Settings';
            app.uipanel1.Tag = 'uipanel1';
            app.lable1 = uilabel(app.uipanel1);
            app.lable1.Tag = 'lable1';
            app.lable1.Text = 'Static Text';
            app.globalSetting = uicheckbox(app.uipanel1);
            app.globalSetting.Tag = 'globalSetting';
            app.globalSetting.Text = 'Global Setting';
            app.checkbox3 = uicheckbox(app.uipanel1);
            app.checkbox3.Tag = 'checkbox3';
            app.checkbox3.Text = 'Setting';
            app.checkbox2 = uicheckbox(app.uipanel1);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'Setting';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'Setting';
            app.trialList = uidropdown(app.uipanel1);
            app.trialList.Items = {'Pop-up Menu'};
            app.trialList.ValueChangedFcn = createCallbackFcn(app, @trialList_Callback, true);
            app.trialList.Tag = 'trialList';
            app.trialList.Value = 'Pop-up Menu';
            app.change = uibutton(app.uipanel1, 'push');
            app.change.ButtonPushedFcn = createCallbackFcn(app, @change_Callback, true);
            app.change.Tag = 'change';
            app.change.Text = 'Change';
            app.path1 = uieditfield(app.uipanel1, 'text');
            app.path1.Tag = 'path1';
            app.path1.Value = 'Edit Text';
            app.currentTrial = uilabel(app.uipanel1);
            app.currentTrial.Tag = 'currentTrial';
            app.currentTrial.Visible = 'off';
            app.currentTrial.Text = '1';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = '100';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Tag = 'edit2';
            app.edit2.Value = '100';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '100';
            app.edit_text1 = uilabel(app.uipanel1);
            app.edit_text1.Tag = 'edit_text1';
            app.edit_text1.Text = 'Setting';
            app.edit_text2 = uilabel(app.uipanel1);
            app.edit_text2.Tag = 'edit_text2';
            app.edit_text2.Text = 'Setting';
            app.edit_text3 = uilabel(app.uipanel1);
            app.edit_text3.Tag = 'edit_text3';
            app.edit_text3.Text = 'Setting';
            app.checkbox4 = uicheckbox(app.uipanel1);
            app.checkbox4.Tag = 'checkbox4';
            app.checkbox4.Text = 'Setting';
            app.edit4 = uieditfield(app.uipanel1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Value = '100';
            app.edit_text4 = uilabel(app.uipanel1);
            app.edit_text4.Tag = 'edit_text4';
            app.edit_text4.Text = 'Setting';
            app.checkbox5 = uicheckbox(app.uipanel1);
            app.checkbox5.Tag = 'checkbox5';
            app.checkbox5.Text = 'Setting';
            app.edit5 = uieditfield(app.uipanel1, 'text');
            app.edit5.Tag = 'edit5';
            app.edit5.Value = '100';
            app.edit_text5 = uilabel(app.uipanel1);
            app.edit_text5.Tag = 'edit_text5';
            app.edit_text5.Text = 'Setting';
            app.text1 = uilabel(app.uipanel2);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Layer settings';
            app.layerName = uilabel(app.uipanel2);
            app.layerName.Tag = 'layerName';
            app.layerName.Text = 'Layer Name';
            app.ok = uibutton(app.uipanel2, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @ok_Callback, true);
            app.ok.Tag = 'ok';
            app.ok.Text = 'OK';
            app.cancel = uibutton(app.uipanel2, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancel_Callback, true);
            app.cancel.Tag = 'cancel';
            app.cancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = layerSettings_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)settings_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end