classdef parameterSettings_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        uipanel1       matlab.ui.container.Panel
        cancel         matlab.ui.control.Button
        ok             matlab.ui.control.Button
        text2          matlab.ui.control.Label
        text1          matlab.ui.control.Label
        uipanel2       matlab.ui.container.Panel
        checkbox4      matlab.ui.control.CheckBox
        currentTrial   matlab.ui.control.Label
        trialList      matlab.ui.control.DropDown
        checkbox1      matlab.ui.control.CheckBox
        checkbox2      matlab.ui.control.CheckBox
        checkbox3      matlab.ui.control.CheckBox
        globalSetting  matlab.ui.control.CheckBox
        text3          matlab.ui.control.Label
    end

    
    methods (Access = private)
        function setValues(app, handles)
            %----------------Set Values------------------------%
            
            
            complex = getappdata(gcf, 'tmpComplex');
            k        = str2num(get(handles.currentTrial, 'String'));
            
            %global settings
            complex(k).global = get(handles.globalSetting, 'Value');
            
            
            %box settings
            if ~strcmp(complex(k).box1{1}, 'OFF')
                complex(k).box1{2} = get(handles.checkbox1, 'Value');
            end
            
            if ~strcmp(complex(k).box2{1}, 'OFF')
                complex(k).box2{2} = get(handles.checkbox2, 'Value');
            end
            
            if ~strcmp(complex(k).box3{1}, 'OFF')
                complex(k).box3{2} = get(handles.checkbox3, 'Value');
            end
            
            if ~strcmp(complex(k).box4{1}, 'OFF')
                complex(k).box4{2} = get(handles.checkbox3, 'Value');
            end
            
            
            setappdata(gcf, 'tmpComplex', complex);
        end
        
        function updateFigure(app, handles)
            
            complex = getappdata(gcf, 'tmpComplex');
            k = str2num(get(handles.currentTrial, 'String'));
            
            %set drop down list
            trialList = [];
            
            C = length(complex);
            for c = 1:C
                trialList{c} = ['Trial ' num2str(c)];
            
                if c == k
                    trialList{c} = strcat(trialList{c}, '*');
                end
            end
            
            set(handles.trialList, 'String', trialList);
            
            %global
            set(handles.globalSetting, 'Value', complex(k).global);
            
            %box settings
            if strcmp(complex(k).box1{1}, 'OFF')
                set(handles.checkbox1, 'Enable', 'Off');
            else
                set(handles.checkbox1, 'Enable', 'On');
                set(handles.checkbox1, 'String', complex(k).box1{1});
                set(handles.checkbox1, 'Value',  complex(k).box1{2});
            end
            
            if strcmp(complex(k).box2{1}, 'OFF')
                set(handles.checkbox2, 'Enable', 'Off');
            else
                set(handles.checkbox2, 'Enable', 'On');
                set(handles.checkbox2, 'String', complex(k).box1{1});
                set(handles.checkbox2, 'Value',  complex(k).box1{2});
            end
            
            if strcmp(complex(k).box3{1}, 'OFF')
                set(handles.checkbox3, 'Enable', 'Off');
            else
                set(handles.checkbox3, 'Enable', 'On');
                set(handles.checkbox3, 'String', complex(k).box1{1});
                set(handles.checkbox3, 'Value',  complex(k).box1{2});
            end
            
            if strcmp(complex(k).box4{1}, 'OFF')
                set(handles.checkbox4, 'Enable', 'Off');
            else
                set(handles.checkbox4, 'Enable', 'On');
                set(handles.checkbox4, 'String', complex(k).box1{1});
                set(handles.checkbox4, 'Value',  complex(k).box1{2});
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function parameterSettings_OpeningFcn(app, varargin)
            % --- Executes just before parameterSettings is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to parameterSettings (see VARARGIN)
            
            % Choose default command line output for parameterSettings
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes parameterSettings wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            %Get current settings
            navData     = getappdata(0, 'navData');
            chstimuli   = getappdata(0, 'chstimuli');
            index       = navData.activeStim;
            z           = navData.marker(index);
            
            layer = chstimuli(index).layers(z);
            
            tmpComplex = layer.complex;
            
            %set layer name
            set(handles.text2, 'String', layer.name);
            
            setappdata(gcf, 'tmpComplex', tmpComplex);
            
            updateFigure(app, handles);
        end

        % Button pushed function: cancel
        function cancel_Callback(app, event)
            % --- Executes on button press in cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(gcf);
        end

        % Button pushed function: ok
        function ok_Callback(app, event)
            % --- Executes on button press in ok.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ok (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %save current values
            setValues(app, handles);
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            complex   = getappdata(gcf, 'tmpComplex');
            
            index = navData.activeStim;
            z     = navData.marker(index);
            
            %save new settings
            chstimuli(index).layers(z).complex = complex;
            
            setappdata(0, 'chstimuli', chstimuli);
            close(gcf);
        end

        % Value changed function: trialList
        function trialList_Callback(app, event)
            % --- Executes on selection change in trialList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to trialList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns trialList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from trialList
            
            newTrial = get(hObject,'Value'); %index of chosen trial
            
            setValues(app, handles);
            
            set(handles.currentTrial, 'String', num2str(newTrial));
            updateFigure(app, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'parameterSettings';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'Trial Settings';
            app.uipanel2.Tag = 'uipanel2';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Complex Parameters';
            app.globalSetting = uicheckbox(app.uipanel2);
            app.globalSetting.Tag = 'globalSetting';
            app.globalSetting.Text = 'Global Setting';
            app.checkbox3 = uicheckbox(app.uipanel2);
            app.checkbox3.Tag = 'checkbox3';
            app.checkbox3.Text = 'Setting';
            app.checkbox2 = uicheckbox(app.uipanel2);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'Setting';
            app.checkbox1 = uicheckbox(app.uipanel2);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'Setting';
            app.trialList = uidropdown(app.uipanel2);
            app.trialList.Items = {'Pop-up Menu'};
            app.trialList.ValueChangedFcn = createCallbackFcn(app, @trialList_Callback, true);
            app.trialList.Tag = 'trialList';
            app.trialList.Value = 'Pop-up Menu';
            app.currentTrial = uilabel(app.uipanel2);
            app.currentTrial.Tag = 'currentTrial';
            app.currentTrial.Text = '1';
            app.checkbox4 = uicheckbox(app.uipanel2);
            app.checkbox4.Tag = 'checkbox4';
            app.checkbox4.Text = 'Setting';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Parameter Settings Gui';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Layer Name';
            app.ok = uibutton(app.uipanel1, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @ok_Callback, true);
            app.ok.Tag = 'ok';
            app.ok.Text = 'OK';
            app.cancel = uibutton(app.uipanel1, 'push');
            app.cancel.ButtonPushedFcn = createCallbackFcn(app, @cancel_Callback, true);
            app.cancel.Tag = 'cancel';
            app.cancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parameterSettings_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)parameterSettings_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end