classdef layerManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1   matlab.ui.Figure
        text7     matlab.ui.control.Label
        text6     matlab.ui.control.Label
        copy      matlab.ui.control.Button
        text5     matlab.ui.control.Label
        ok        matlab.ui.control.Button
        remove    matlab.ui.control.Button
        moveDown  matlab.ui.control.Button
        moveUp    matlab.ui.control.Button
        text4     matlab.ui.control.Label
        text3     matlab.ui.control.Label
        text1     matlab.ui.control.Label
        avlayers  matlab.ui.control.ListBox
        chlayers  matlab.ui.control.ListBox
    end

    
    methods (Access = private)
        function updateFigure(app, handles)
            
            avstimuli  = getLayer('List');
            chstimuli  = getappdata(0, 'chstimuli');
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            
            index = navData.activeStim;
            
            %Update listboxes
            names_avlayers = avstimuli;
            names_chlayers = {chstimuli(index).layers.name};
            
            set(handles.avlayers,'String',names_avlayers);
            set(handles.chlayers,'String',names_chlayers);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function layerManager_OpeningFcn(app, varargin)
            % --- Executes just before layerManager is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to layerManager (see VARARGIN)
            
            % Choose default command line output for layerManager
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes layerManager wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            setappdata(gcf, 'updateCaller', varargin);
            updateFigure(app, handles);
        end

        % Value changed function: avlayers
        function avlayers_Callback(app, event)
            % --- Executes on selection change in avlayers.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to avlayers (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns avlayers contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from avlayers
            
            
            %add chosen layers to chlayers
            if strcmp(get(handles.figure1,'SelectionType'),'open') %check if doubleclick
            
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
            
                index        = get(app.avlayers,'ValueIndex');  %get index of selected object
            
            
                [tmp, numRuns] = size(chstimuli(navData.activeStim).layers(1).data);
            
                new_layer    = getLayer(index);     % Chosen stimuli to add
                data         = new_layer.data;
                settings     = new_layer.settings;
                [tmp, C]       = size(data);
            
                if C < numRuns
                    for c = 1:(numRuns-C)
                        data(:,c+1)     = data(:,c);
                        settings(:,c+1) = settings(:,c);
                    end
                end
            
                new_layer.data     = data;
                new_layer.settings = settings;
                write_index        = length(chstimuli(navData.activeStim).layers) +1; % Index to write to (last in list)
            
                chstimuli(navData.activeStim).layers(write_index) = new_layer; % Add to array
            
            
                setappdata(0, 'chstimuli', chstimuli); % save changes
                setappdata(0, 'navData',   navData);   % save changes
            
                app.chlayers.Items{write_index} = new_layer.name;
                set(handles.chlayers,'Value',write_index);    % update marker
            
                updateFigure(app, handles); %update
            end
        end

        % Button pushed function: copy
        function copy_Callback(app, event)
            % --- Executes on button press in copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get selected index of listbox2
            index     = get(handles.chlayers, 'Value');          %to be copied
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            layers    = chstimuli(navData.activeStim).layers;
            
            %check if there are any objects to copy and disallow removing main
            if ~isempty(layers)
            
                N = length(layers) - index;  %number of entries needs to move
            
                for n = 0:N+1
                    layers(end+1-n) = layers(end-n);
                end
            
                chstimuli(navData.activeStim).layers = layers;
            
                setappdata(0, 'chstimuli', chstimuli); %Save changes
            
                updateFigure(app, handles);
            end
        end

        % Button pushed function: moveDown
        function moveDown_Callback(app, event)
            % --- Executes on button press in moveDown.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to moveDown (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get selected index of listbox2
            index     = get(handles.chlayers, 'Value');
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            layers = chstimuli(navData.activeStim).layers;
            
            if index < length(layers)
            
                tmp = layers(index);
                layers(index)   = layers(index+1);
                layers(index+1) = tmp;
            
                chstimuli(navData.activeStim).layers = layers;
                setappdata(0, 'chstimuli', chstimuli);
            
                set(handles.chlayers,'Value',index+1);
                updateFigure(app, handles);
            end
        end

        % Button pushed function: moveUp
        function moveUp_Callback(app, event)
            % --- Executes on button press in moveUp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to moveUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get selected index of listbox2
            index     = get(handles.chlayers, 'Value');
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            layers = chstimuli(navData.activeStim).layers;
            
            if index > 1 %over 1, move up
            
                tmp = layers(index);
                layers(index)   = layers(index-1);
                layers(index-1) = tmp;
            
                chstimuli(navData.activeStim).layers = layers;
                setappdata(0, 'chstimuli', chstimuli);
            
                set(handles.chlayers,'Value',index-1);
                updateFigure(app, handles);
            end
        end

        % Button pushed function: ok
        function ok_Callback(app, event)
            % --- Executes on button press in ok.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ok (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            updateCaller   = getappdata(gcf, 'updateCaller');
            updateFunction = updateCaller{1};
            updateHandles  = updateCaller{2};
            updateFunction(updateHandles);
            
            close(gcf);
        end

        % Button pushed function: remove
        function remove_Callback(app, event)
            % --- Executes on button press in remove.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to remove (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get selected index of listbox2
            index     = get(handles.chlayers, 'Value');          %to be removed
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            layers = chstimuli(navData.activeStim).layers;
            
            %check if there are any objects to remove and disallow removing main
            if length(layers) == 1
                disp(' -Need at least 1 layer!');
            else
            
                N = length(layers) - index;  %number of entries needs to move
            
                for n = 0:N-1
                    layers(index+n) = layers(index+n+1);
                end
                layers = layers(1:end-1); %remove last entry
            
                if navData.marker(navData.activeStim) > length(layers)
                    navData.marker(navData.activeStim) = navData.marker(navData.activeStim) -1;
                end
            
                chstimuli(navData.activeStim).layers = layers;
            
                setappdata(0, 'chstimuli', chstimuli); %Save changes
                setappdata(0, 'navData',   navData);
            
                if index > length(layers)
                    set(handles.chlayers,'Value',index-1);  %move up index
                end
            
                updateFigure(app, handles);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'layerManager';
            app.figure1.Tag = 'figure1';
            app.chlayers = uilistbox(app.figure1);
            app.chlayers.Items = {'Listbox'};
            app.chlayers.Tag = 'chlayers';
            app.chlayers.Value = 'Listbox';
            app.avlayers = uilistbox(app.figure1);
            app.avlayers.Items = {'Listbox'};
            app.avlayers.ValueChangedFcn = createCallbackFcn(app, @avlayers_Callback, true);
            app.avlayers.Tag = 'avlayers';
            app.avlayers.Value = 'Listbox';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Layer templates';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Current layers';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Layer manager';
            app.moveUp = uibutton(app.figure1, 'push');
            app.moveUp.ButtonPushedFcn = createCallbackFcn(app, @moveUp_Callback, true);
            app.moveUp.Tag = 'moveUp';
            app.moveUp.Text = 'Move Up';
            app.moveDown = uibutton(app.figure1, 'push');
            app.moveDown.ButtonPushedFcn = createCallbackFcn(app, @moveDown_Callback, true);
            app.moveDown.Tag = 'moveDown';
            app.moveDown.Text = 'Move Down';
            app.remove = uibutton(app.figure1, 'push');
            app.remove.ButtonPushedFcn = createCallbackFcn(app, @remove_Callback, true);
            app.remove.Text = 'Remove';
            app.ok = uibutton(app.figure1, 'push');
            app.ok.ButtonPushedFcn = createCallbackFcn(app, @ok_Callback, true);
            app.ok.Tag = 'ok';
            app.ok.Text = 'OK';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Note: All changes made here are immediate (no cancel!)';
            app.copy = uibutton(app.figure1, 'push');
            app.copy.ButtonPushedFcn = createCallbackFcn(app, @copy_Callback, true);
            app.copy.Tag = 'copy';
            app.copy.Text = 'Copy';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Top';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Bottom';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = layerManager(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)layerManager_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end