classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        File        matlab.ui.container.Menu
        new         matlab.ui.container.Menu
        open        matlab.ui.container.Menu
        save_as     matlab.ui.container.Menu
        importStim  matlab.ui.container.Menu
        exit        matlab.ui.container.Menu
        tools       matlab.ui.container.Menu
        settings    matlab.ui.container.Menu
        rename      matlab.ui.control.Button
        copy        matlab.ui.control.Button
        title       matlab.ui.control.Label
        test        matlab.ui.control.Button
        killScreen  matlab.ui.control.Button
        initScreen  matlab.ui.control.Button
        text2       matlab.ui.control.Label
        text1       matlab.ui.control.Label
        remove      matlab.ui.control.Button
        moveDown    matlab.ui.control.Button
        moveUp      matlab.ui.control.Button
        listbox2    matlab.ui.control.ListBox
        listbox1    matlab.ui.control.ListBox
        next        matlab.ui.control.Button
    end

    
    methods (Access = private)
        function gotoStim(app, handles, stimNo)
            
            %load data
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            %update
            if length(chstimuli) >= stimNo %should always be the case
            
                navData.activeStim = stimNo;                     %update index
                setappdata(0, 'navData', navData);                  %save navData
            
                %switch gui
                nextGui  = chstimuli(stimNo).fileNameGui;
                delete(gcf) %close current gui
            
                try
                    eval(nextGui); %start new gui
            
                catch e
                    disp(['Error evaluating ' nextGui ':']);
                    disp(e.message);
            
                    navData.activeStim = 1;
                    setappdata(0, 'navData', navData);
                    main;
                end
            end
        end
        
        function updateFigure(app, handles)
            
            %Read appdata
            chstimuli  = getappdata(0, 'chstimuli');
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            
            %Update listboxes
            names_avstimuli = getLayer('List');
            names_chstimuli = {chstimuli.name};
            
            set(handles.listbox1,'String',names_avstimuli);
            set(handles.listbox2,'String',names_chstimuli);
            
            %Update the next/previous buttons
            navFcn(handles, 'Update');
            
            %Update kill/init screen buttons
            if screenData.isInit
                set(handles.initScreen,'Enable','off'); %grey out init button
                set(handles.killScreen,'Enable','on');  %enable kill button
                set(handles.test,'Enable','off');        %enable test button
            else
                set(handles.initScreen,'Enable','on');   %grey out init button
                set(handles.killScreen,'Enable','off');  %disable kill button
                set(handles.test,'Enable','on');        %disable test button
            end
            
            %Title - Update the title so that it is correct.
            set(handles.figure1, 'Name', [navData.fileName]);
        end

        function updateIndexes(app)
            app.listbox2.ItemsData = 1:length(app.listbox2.Items);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app, varargin)
            % --- Executes just before main is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to main (see VARARGIN)
            
            % Choose default command line output for main
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes main wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            addpath(cd, [cd '/' genpath('Subfunctions')], [cd '/' genpath('Stimuli')]); %Add subfuntions in separate folder
            hGui = gcf;       %get handle to figure
            
            %Initialize
            navFcn(handles, 'AddHandle', hGui); %save the handle (in root)
            updateFigure(app, handles);              % Populate the listbox
            set(handles.listbox1,'Value',1);    % Default selection
            
            
            %set position of window
            navData   = getappdata(0, 'navData');
            
            screenRes = get(0, 'ScreenSize');   %[1 1 screenWidth screenHeight]
            
            set(gcf, 'Units' , 'Pixels');
            posdata = get(gcf,'Position');
            height = posdata(4);
            width = posdata(3);
            set(gcf, 'Position', [navData.windowPosition (screenRes(4)-height)/2 width height]);
        end

        % Button pushed function: copy
        function copy_Callback(app, event)
            % --- Executes on button press in copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get selected index of listbox2
            index     = get(handles.listbox2,'Value');          %to be copied
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            %check if there are any objects to remove and disallow removing main
            if ~isempty(chstimuli) && index ~= 1
                N = length(chstimuli) - index;  %number of entries needs to move
            
                for n = 0:N+1
                    chstimuli(end+1-n) = chstimuli(end-n);
                end
            
                navData.numStim = navData.numStim + 1; %update number of stims in list
            
                setappdata(0, 'chstimuli', chstimuli); %Save changes
                setappdata(0, 'navData',   navData);
            
            
                navData.marker = navData.marker([1:index index:end]);
                updateIndexes(app)
                updateFigure(app, handles);
            end
            
            setappdata(0, 'navData', navData);
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(gcf);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close figure1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            
            navFcn(handles, 'Close');
            delete(hObject);
        end

        % Menu selected function: importStim
        function importStim_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to importStim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            menuFcn('importStim');
            updateFigure(app, handles);
        end

        % Button pushed function: initScreen
        function initScreen_Callback(app, event)
            % --- Executes on button press in initScreen.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to initScreen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            screenFcn('Init');
            updateFigure(app, handles);
        end

        % Button pushed function: killScreen
        function killScreen_Callback(app, event)
            % --- Executes on button press in killScreen.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to killScreen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            screenFcn('Kill');
            updateFigure(app, handles);
        end

        % Value changed function: listbox1
        function listbox1_Callback(app, event)
            % --- Executes on selection change in listbox1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox1
            
            
            %add chosen stimuli to chstimuli
            if strcmp(get(app.figure1,'SelectionType'),'open') %check if doubleclick
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
            
                index     = get(app.listbox1,'ValueIndex');  %get index of selected object
                new_layer = getLayer(index);      % Chosen layer to start with
            
                write_index = length(chstimuli) +1; % Index to write to (last in list)
            
                %make new stimuli
                new_entry.name          = new_layer.name;
                new_entry.fileNameGui   = 'tableGui';
                new_entry.settingsGui   = 'settingsGui';
                new_entry.hGui          = 0;
                new_entry.layers        = new_layer;
                new_entry.targetBgColor       = 255;
                new_entry.stimSettings  = 0;
            
                chstimuli(write_index)  = new_entry; % Add to array
                navData.numStim         = navData.numStim + 1; % Update number of stims in list
                navData.marker          = [navData.marker 1];
            
                setappdata(0, 'chstimuli', chstimuli); % save changes
                setappdata(0, 'navData',   navData);   % save changes
            
                app.listbox2.Items{write_index} = new_layer.name;
                set(app.listbox2, 'ValueIndex', write_index); % update marker
                updateIndexes(app)
                updateFigure(app, handles); %update
            end
        end

        % Value changed function: listbox2
        function listbox2_Callback(app, event)
            % --- Executes on selection change in listbox2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox2
            
            %For debug purposes
            if strcmp(get(handles.figure1,'SelectionType'),'open') %check if doubleclick
            
                index = get(handles.listbox2,'Value');  %get index of selected object
            
                gotoStim(app, handles, index);
            end
        end

        % Button pushed function: moveDown
        function moveDown_Callback(app, event)
            % --- Executes on button press in moveDown.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to moveDown (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get selected index of listbox2
            index = get(handles.listbox2,'Value');
            chstimuli = getappdata(0, 'chstimuli');
            
            if index < length(chstimuli) && index~= 1
                %move down if possible, keep main at top
            
                tmp = chstimuli(index);
                chstimuli(index) = chstimuli(index+1);
                chstimuli(index+1) = tmp;
                setappdata(0, 'chstimuli', chstimuli);
            
                set(handles.listbox2,'Value',index+1);
            
                updateIndexes(app)
                updateFigure(app, handles);
            end
        end

        % Button pushed function: moveUp
        function moveUp_Callback(app, event)
            % --- Executes on button press in moveUp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to moveUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get selected index of listbox2
            index     = get(handles.listbox2, 'Value');
            chstimuli = getappdata(0, 'chstimuli');
            if index > 2 %over 2, move up (Keep main at top)
            
                tmp = chstimuli(index);
                chstimuli(index)   = chstimuli(index-1);
                chstimuli(index-1) = tmp;
                setappdata(0, 'chstimuli', chstimuli);
            
                set(handles.listbox2,'Value',index-1);
            
                updateIndexes(app)
                updateFigure(app, handles);
            end
        end

        % Menu selected function: new
        function new_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to new (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            menuFcn('New');
        end

        % Button pushed function: next
        function next_Callback(app, event)
            % --- Executes on button press in next.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to next (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %load data
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index = navData.activeStim;                         %current stim
            gotoStim(app, handles, index+1);
        end

        % Menu selected function: open
        function open_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to open (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            menuFcn('Open');
            
            navData = getappdata(0, 'navData');
            %navData.activeStim = 1;
            setappdata(0, 'navData', navData);
            
            updateFigure(app, handles);
        end

        % Button pushed function: remove
        function remove_Callback(app, event)
            % --- Executes on button press in remove.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to remove (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get selected index of listbox2
            index     = get(handles.listbox2,'Value');          %to be removed
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            %check if there are any objects to remove and disallow removing main
            if ~isempty(chstimuli) && index ~= 1
                N = length(chstimuli) - index;  %number of entries needs to move
            
                for n = 0:N-1
                    chstimuli(index+n) = chstimuli(index+n+1);
                end
            
                chstimuli = chstimuli(1:end-1); %remove last entry
                navData.numStim = navData.numStim - 1; %update number of stims in list
            
                setappdata(0, 'chstimuli', chstimuli); %Save changes
                setappdata(0, 'navData',   navData);
            
                if index > length(chstimuli)
                    set(handles.listbox2,'Value',index-1);  %move up index
                end
            
                navData.marker = navData.marker([1:index-1 index+1:end]); %remove marker at index
                updateIndexes(app)
                updateFigure(app, handles);
            end
            
            setappdata(0, 'navData', navData);
        end

        % Button pushed function: rename
        function rename_Callback(app, event)
            % --- Executes on button press in rename.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rename (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %get selected index of listbox2
            index     = get(handles.listbox2,'Value');          %to be renamed
            chstimuli = getappdata(0, 'chstimuli');
            
            newName = inputdlg({'Enter new name: '});
            
            if ~isempty(newName)
                chstimuli(index).name = newName{1};
                setappdata(0, 'chstimuli', chstimuli);
                updateFigure(app, handles);
            end
        end

        % Menu selected function: save_as
        function save_as_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to save_as (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            menuFcn('SaveAs');
            updateFigure(app, handles);
        end

        % Menu selected function: settings
        function settings_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to settings (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            mainSettings;
        end

        % Button pushed function: test
        function test_Callback(app, event)
            % --- Executes on button press in test.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to test (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            screenFcn('Init');
            
            pause(2);
            screenFcn('Kill');
            disp('Screen Closed');
            
            updateFigure(app, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'main';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.File = uimenu(app.figure1);
            app.File.Text = 'File';
            app.File.Tag = 'File';
            app.new = uimenu(app.File);
            app.new.MenuSelectedFcn = createCallbackFcn(app, @new_Callback, true);
            app.new.Text = 'New';
            app.new.Tag = 'new';
            app.open = uimenu(app.File);
            app.open.MenuSelectedFcn = createCallbackFcn(app, @open_Callback, true);
            app.open.Text = 'Open';
            app.open.Tag = 'open';
            app.save_as = uimenu(app.File);
            app.save_as.MenuSelectedFcn = createCallbackFcn(app, @save_as_Callback, true);
            app.save_as.Text = 'Save as';
            app.save_as.Tag = 'save_as';
            app.importStim = uimenu(app.File);
            app.importStim.MenuSelectedFcn = createCallbackFcn(app, @importStim_Callback, true);
            app.importStim.Text = 'Import Stimuli';
            app.importStim.Tag = 'importStim';
            app.exit = uimenu(app.File);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Text = 'Exit';
            app.exit.Tag = 'exit';
            app.tools = uimenu(app.figure1);
            app.tools.Text = 'Tools';
            app.tools.Tag = 'tools';
            app.settings = uimenu(app.tools);
            app.settings.MenuSelectedFcn = createCallbackFcn(app, @settings_Callback, true);
            app.settings.Text = 'Settings';
            app.settings.Tag = 'settings';
            app.next = uibutton(app.figure1, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @next_Callback, true);
            app.next.Tag = 'next';
            app.next.Text = 'Next';
            app.listbox1 = uilistbox(app.figure1);
            app.listbox1.Items = {};
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
            app.listbox1.Tag = 'listbox1';
            app.listbox1.Value = {};
            app.listbox2 = uilistbox(app.figure1);
            app.listbox2.Items = {};
            app.listbox2.ValueChangedFcn = createCallbackFcn(app, @listbox2_Callback, true);
            app.listbox2.Tag = 'listbox2';
            app.listbox2.Value = {};
            app.moveUp = uibutton(app.figure1, 'push');
            app.moveUp.ButtonPushedFcn = createCallbackFcn(app, @moveUp_Callback, true);
            app.moveUp.Tag = 'moveUp';
            app.moveUp.Text = 'Move Up';
            app.moveDown = uibutton(app.figure1, 'push');
            app.moveDown.ButtonPushedFcn = createCallbackFcn(app, @moveDown_Callback, true);
            app.moveDown.Tag = 'moveDown';
            app.moveDown.Text = 'Move Down';
            app.remove = uibutton(app.figure1, 'push');
            app.remove.ButtonPushedFcn = createCallbackFcn(app, @remove_Callback, true);
            app.remove.Text = 'Remove';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Available stimuli';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Chosen stimuli';
            app.initScreen = uibutton(app.figure1, 'push');
            app.initScreen.ButtonPushedFcn = createCallbackFcn(app, @initScreen_Callback, true);
            app.initScreen.Tag = 'initScreen';
            app.initScreen.Text = 'Init Screen';
            app.killScreen = uibutton(app.figure1, 'push');
            app.killScreen.ButtonPushedFcn = createCallbackFcn(app, @killScreen_Callback, true);
            app.killScreen.Tag = 'killScreen';
            app.killScreen.Text = 'Kill Screen';
            app.test = uibutton(app.figure1, 'push');
            app.test.ButtonPushedFcn = createCallbackFcn(app, @test_Callback, true);
            app.test.Tag = 'test';
            app.test.Text = 'Test';
            app.title = uilabel(app.figure1);
            app.title.Tag = 'title';
            app.title.Text = 'Main';
            app.copy = uibutton(app.figure1, 'push');
            app.copy.ButtonPushedFcn = createCallbackFcn(app, @copy_Callback, true);
            app.copy.Tag = 'copy';
            app.copy.Text = 'Copy';
            app.rename = uibutton(app.figure1, 'push');
            app.rename.ButtonPushedFcn = createCallbackFcn(app, @rename_Callback, true);
            app.rename.Tag = 'rename';
            app.rename.Text = 'Rename';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)main_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end