classdef tableGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        file                          matlab.ui.container.Menu
        new                           matlab.ui.container.Menu
        open                          matlab.ui.container.Menu
        saveAs                        matlab.ui.container.Menu
        importStim                    matlab.ui.container.Menu
        exit                          matlab.ui.container.Menu
        tools                         matlab.ui.container.Menu
        recordStimulus                matlab.ui.container.Menu
        avimovie                      matlab.ui.container.Menu
        imsequence                    matlab.ui.container.Menu
        settings                      matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        StandardtoolsTab              matlab.ui.container.Tab
        PreviewVideoPanel             matlab.ui.container.Panel
        previewVid                    matlab.ui.control.Button
        uipanel2                      matlab.ui.container.Panel
        chosenRow                     matlab.ui.control.EditField
        rowLog                        matlab.ui.control.Button
        rowShuffle_1                  matlab.ui.control.Button
        useValue1                     matlab.ui.control.Button
        chosenCol                     matlab.ui.control.EditField
        valNum                        matlab.ui.control.EditField
        shiftColLeft                  matlab.ui.control.Button
        shiftColRight                 matlab.ui.control.Button
        rowLin                        matlab.ui.control.Button
        text10                        matlab.ui.control.Label
        allRows                       matlab.ui.control.CheckBox
        uipanel1                      matlab.ui.container.Panel
        text5                         matlab.ui.control.Label
        numRuns                       matlab.ui.control.EditField
        numRunsOk                     matlab.ui.control.Button
        trialSubset                   matlab.ui.control.CheckBox
        trials                        matlab.ui.control.EditField
        AdvancedtoolsTab              matlab.ui.container.Tab
        uipanel18_2                   matlab.ui.container.Panel
        UnneededifusingguvcviewLabel  matlab.ui.control.Label
        videoDuringStim               matlab.ui.control.CheckBox
        uipanel18                     matlab.ui.container.Panel
        mseq                          matlab.ui.control.Button
        mseqChange                    matlab.ui.control.EditField
        mseqStart                     matlab.ui.control.EditField
        useCumsum                     matlab.ui.control.CheckBox
        uipanel22                     matlab.ui.container.Panel
        applyWhiteNoiseBtn            matlab.ui.control.Button
        gaussianWhiteNoiseVariance    matlab.ui.control.EditField
        text35                        matlab.ui.control.Label
        uipanel23                     matlab.ui.container.Panel
        generateXYbutton              matlab.ui.control.Button
        uitable1                      matlab.ui.control.Table
        uipanel16                     matlab.ui.container.Panel
        impulse                       matlab.ui.control.CheckBox
        stimSettings                  matlab.ui.control.Button
        editLayerName                 matlab.ui.control.Button
        layerList                     matlab.ui.control.DropDown
        layerManager                  matlab.ui.control.Button
        layerName                     matlab.ui.control.Label
        editName                      matlab.ui.control.Button
        stimName                      matlab.ui.control.Label
        text17                        matlab.ui.control.Label
        resetMissed                   matlab.ui.control.Button
        info                          matlab.ui.control.Label
        uipanel13                     matlab.ui.container.Panel
        text36                        matlab.ui.control.Label
        applyBgColour                 matlab.ui.control.Button
        editBgColor                   matlab.ui.control.EditField
        stimList                      matlab.ui.control.DropDown
        clear                         matlab.ui.control.Button
        grid                          matlab.ui.control.Button
        killScreen                    matlab.ui.control.Button
        initScreen                    matlab.ui.control.Button
        run                           matlab.ui.control.Button
        next                          matlab.ui.control.Button
        previous                      matlab.ui.control.Button
    end

    
    methods (Access = private)
        function ImpFreq_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to ImpFreq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function ImpRange1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to ImpRange1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function ImpRange2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to ImpRange2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function ImpRangeMethod_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to ImpRangeMethod (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function change_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in change.
            
            % hObject    handle to change (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ImpSet=getappdata(0,'ImpSet');
            if exist(fullfile(pwd,'curr_ImpSet.mat'),'file')==2
                if (isdir(fullfile(pwd,'oldImpSets')))==0
                    mkdir(fullfile(pwd,'oldImpSets'))
                end
                currImpSets=load('curr_ImpSet.mat');
                c=clock;
                save( fullfile(pwd,'oldImpSets',['\ImpSet_',num2str(c(1)),'_',num2str(c(2)),'_',num2str(c(3)),'_',num2str(c(4)),'_',num2str(c(5))]), 'currImpSets');
            end
            save( fullfile(pwd,'curr_ImpSet.mat'),'ImpSet')
        end
        
        function createImpStim_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in createImpStim.
            
            numRuns  = round(abs(str2num(get(handles.numSeries, 'String'))));
            numSeqs     = get(handles.numSeqs, 'String');
            timeSeq     = get(handles.time, 'String');
        end
        
        function edit15_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit15 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit16_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit16 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit17_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit17 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit22_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit22 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit23_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit23 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit28_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit28 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit29_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit29 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit30_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit30 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit31_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit31 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit35_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit35 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit36_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit36 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit37_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit37 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit38_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit38 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit39_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit39 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit40_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit40 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function gotoLayer(app, handles, layerNO)
            
            chstimuli  = getappdata(0, 'chstimuli');
            navData    = getappdata(0, 'navData');
            index      = navData.activeStim;
            z          = navData.marker(index);
            
            if z ~= layerNO %dont change if press on current layer
            
                %update
                navData.marker(index) = layerNO;                 %update index
                setappdata(0, 'navData', navData);               %save navData
            
                updateTable(app, handles);
            end
        end
        
        function gotoStim(app, handles, stimNO)
            
            chstimuli  = getappdata(0, 'chstimuli');
            navData    = getappdata(0, 'navData');
            index      = navData.activeStim;
            
            %dont change if press on current stim or if last stim in list
            if index ~= stimNO && stimNO <= length(chstimuli)
                %update
                navData.activeStim = stimNO;                     %update index
                setappdata(0, 'navData', navData);               %save navData
            
                %If the next gui belongs to the same template class we only change
                %the content of the gui. If the template differs we need to open
                %(eval) a new gui.
                if strcmp(chstimuli(index).fileNameGui, chstimuli(stimNO).fileNameGui)
                    updateFigure(app, handles);
                else
                    %switch gui
                    nextGui  = chstimuli(stimNO).fileNameGui;
            
                    delete(gcf)    %close current gui
                    eval(nextGui); %start new gui
                end
            end
        end
        
        function impFreq_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to impFreq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function initBgColorEditBox(app, hObject)
                navData    = getappdata(0, 'navData');
                chstimuli  = getappdata(0, 'chstimuli');
                index    = navData.activeStim;
                if ~isfield(chstimuli(index), 'targetBgColor')
                    chstimuli(index).targetBgColor = 255;
                    setappdata(0, 'chstimuli', chstimuli);
                end
                set(hObject, 'String', num2str(chstimuli(index).targetBgColor));
        end
        
        function loadImpSet_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in loadImpSet.
            
            % hObject    handle to loadImpSet (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if exist(fullfile(pwd,'curr_ImpSet.m'),'file')==2
                ImpSet=load(fullfile(pwd,'curr_ImpSet.mat'));
                setappdata(0,'ImpSet',ImpSet)
            else warndlg('No file ''curr_Impset'' in current file directory.')
            end
        end
        
        function numSeqs_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to numSeqs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function numStepSizes_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to numStepSizes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbutton49_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton49.
            
            % hObject    handle to pushbutton49 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if exist(fullfile(pwd,'curr_ImpSet.m'),'file')==2
                ImpSet=load(fullfile(pwd,'curr_ImpSet.mat'));
                setappdata(0,'ImpSet',ImpSet)
            else; warndlg('No file ''curr_Impset'' in current file directory.')
            end
        end
        
        function pushbutton50_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton50. CHANGE IMPSET
            
            % hObject    handle to pushbutton50 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ImpSet=getappdata(0,'ImpSet');
            if exist(fullfile(pwd,'curr_ImpSet.mat'),'file')==2
                if (isdir(fullfile(pwd,'oldImpSets')))==0
                    mkdir(fullfile(pwd,'oldImpSets'))
                end
                currImpSets=load('curr_ImpSet.mat');
                c=clock;
                save( fullfile(pwd,'oldImpSets',['\ImpSet_',num2str(c(1)),'_',num2str(c(2)),'_',num2str(c(3)),'_',num2str(c(4)),'_',num2str(c(5))]), 'currImpSets');
            end
            save( fullfile(pwd,'curr_ImpSet.mat'),'ImpSet')
        end
        
        function pushbutton51_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton51.
            
            % hObject    handle to pushbutton51 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            chstimuli=getappdata(0,'chstimuli');
            settings = chstimuli(1,2).layers.settings;
            
            %STIMULI
            numSequences  = 10;     %number of displayed msequences []
            trPerSeq      = 255;    %length of msequence []
            y_ImpRange1   = 0.01; % min yaw stepsize [degrees]
            y_ImpRange2   = 10;   % max yaw stepsize [degrees]
            y_numSteps    = 15;    % number of different yaw stepsizes []
            s_ImpRange1   = 0.01; % min sideslip stepsize [cm]
            s_ImpRange2   = 10;      %max sideslip stepsize [cm]
            s_numSteps    = 15;    % number of different sideslip stepsizes []
            ImpFreq       = 60;     % impulse frequency [Hz]
            
            % Logarithmic distribution of yaw and sideslip stepsizes and conversion to
            % velocities (flyfly definition: velocity = Frequency*jumpsize)
            yaw_range       = 120* logspace(log10(y_ImpRange1),log10(y_ImpRange2),y_numSteps); % [degrees/s]
            sideslip_range  = 120* logspace(log10(s_ImpRange1),log10(s_ImpRange2),s_numSteps); % [cm/s]
            
            % create data dummie
            dotSize=5;
            numDots=5000;
            sideslip=0; %will be changed in loop [cm/s]
            lift=0;
            thrust=0;
            pitch=0;
            yaw=0; %will be changed in loop [deg/s]
            roll=0;
            time=120/ImpFreq; %how frames the image will be rendered [frames]
            pause=40;        %will be changed once for every sequence
            prestim=120; %will be changed once for every sequence
            poststim=120; %will be changed once for every sequence
            data=repmat([dotSize;numDots;sideslip;lift;thrust;pitch;yaw;roll;time;0;0;0],1,255*numSequences);
            
            mSeqPar=[]; %save msequence parameers: [shift , which; ...]
            
            for sequence=1:numSequences
                %randomly choose yaw/sideslip and stepsize
                if (rand>0.5)
                    row=7;%yaw
                    stepsize = yaw_range(ceil(rand*y_numSteps));
                else
                    row =3;%sideslip
                    stepsize = sideslip_range(ceil(rand*s_numSteps));
                end
            
                %create m-sequence
                baseVal=2; p=8; shift=round(rand*255); which=round(rand*5);
                msequence= mseq(baseVal,p,shift,which);
                mSeqPar(end+1,1:2)=[shift,which];
            
                %change data
                data(row, (sequence-1)*trPerSeq+1 : sequence*trPerSeq) = msequence*stepsize;
                data(11, (sequence-1)*trPerSeq+1)                      = prestim;
                data(12,                            sequence*trPerSeq) = poststim;
                data(10,                            sequence*trPerSeq) = pause;
            end
            if size(settings,2)<255*numSequences
                settings(:, 2:255*numSequences) = settings(:, 1);
            end
            save([date,'_mSeqPar.mat'],'mSeqPar');
            chstimuli(1,2).layers.data=data;
            chstimuli(1,2).layers.settings=settings;
            setappdata(0, 'chstimuli', chstimuli);
            updateFigure(app, handles);
        end
        
        function rowInterp(app, handles, Row, Mode)
            
            %Interpolate from start to stop values linearly
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index    = navData.activeStim;
            z        = navData.marker(index);
            
            data     = chstimuli(index).layers(z).data;
            
            [R C]  = size(data);
            
            if Row <= R && Row >= 1
            
                firstVal = data(Row, 1);
                lastVal  = data(Row, C);
            
                switch Mode
                    case 'LIN'
                        data(Row, 1:end) = linspace(firstVal, lastVal, C);
                    case 'LOG'
                        data(Row, 1:end) = logspace(log10(firstVal), log10(lastVal), C);
                    otherwise
                        disp(['Incorrect case(rowInterp: ' Mode ')']);
                end
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            end
        end
        
        function rowShuffle(app, handles, Row)
            
            %Interpolate from start to stop values linearly
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index    = navData.activeStim;
            z        = navData.marker(index);
            
            data     = chstimuli(index).layers(z).data;
            
            [R C]    = size(data);
            
            if Row <= R && Row >= 1
            
                data(Row,:) = Shuffle(data(Row,:));
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            end
        end
        
        function shiftColumn(app, step, handles)
            % shift entire column by step
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index    = navData.activeStim;
            z        = navData.marker(index);
            
            data     = chstimuli(index).layers(z).data;
            
            selectedCol = str2num(get(handles.chosenCol, 'String'));
            
            [R C] = size(data);     % size of current data
            
            moveToCol = min(max(selectedCol+step,1),C);
            
            if moveToCol~=selectedCol
            
                temp = data(:,moveToCol);
                data(:,moveToCol) = data(:,selectedCol);
                data(:,selectedCol) = temp;
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            
                set(handles.chosenCol, 'String', num2str(moveToCol));
            
                updateFigure(app, handles);
            
            end
        end
        
        function time_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to time (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function trialStop_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to trialStop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function updateFigure(app, handles)
            % --- Updates the figure
            
            navFcn(handles, 'Update'); %Updates the previous and next buttons
            updateTable(app, handles);
            drawnow;
        end
        
        function updateTable(app, handles)
            %------------------------------------------------------------------
            %--- Executes when the tableGui is updated
            
            navData     = getappdata(0, 'navData');  %Load navigation data
            screenData  = getappdata(0, 'screenData');  %Load navigation data
            chstimuli   = getappdata(0, 'chstimuli');
            
            %Read parameters to set (default values of chstimuli(index))
            index      = navData.activeStim;
            Z          = length(chstimuli(index).layers);
            z          = navData.marker(index);
            
            if z > Z
                z = Z;
                navData.marker(index) = Z; %if we lowered the number of objects
            end
            
            data  = chstimuli(index).layers(z).data;
            names = chstimuli(index).layers(z).parameters;
            impulse = chstimuli(index).layers(z).impulse;
            
            [tmp, C] = size(data);
            
            % Check or uncheck impulse
            set(handles.impulse, 'Value', impulse);
            
            %Set the table data
            set(handles.uitable1, 'Data', data);
            
            %Set row names
            set(handles.uitable1, 'RowName', names);
            
            %Make table editable
            columnEditable  = true(1, C);
            set(handles.uitable1, 'ColumnEditable', columnEditable);
            
            columnFormat = cell(1,C);
            columnFormat = cellfun(@(x) 'numeric', columnFormat, 'UniformOutput', false);
            set(handles.uitable1, 'ColumnFormat',columnFormat);
            
            %Set columnnames and width
            for c = 1:C
                columnName{c} = strcat('Trial_', num2str(c));
                columnWidth{c} = navData.colWidth;
            end
            set(handles.uitable1, 'ColumnName',  columnName);
            set(handles.uitable1, 'ColumnWidth', columnWidth);
            
            %set numRuns
            set(handles.numRuns, 'String', num2str(C));
            
            %Title - Update the title so that it is correct.
            set(handles.stimName,  'String', chstimuli(index).name);
            
            %layername
            set(handles.layerName,  'String', ['Layer ' num2str(z) ':' chstimuli(index).layers(z).name]);
            
            %Update project name
            set(handles.figure1, 'Name', ['FlyFly 4.1: ' navData.fileName]);
            
            %set info label
            if screenData.isInit
                newInfo{1} = ['Screen: ' num2str(screenData.partial(3)) 'x' ...
                    num2str(screenData.partial(4)) ' px, ' ...
                    num2str(screenData.hz) ' Hz'];
            else
                newInfo{1} = 'Screen: Not initialized';
            end
            
            newInfo{2} = ['Skipped frames: ' num2str(navData.skippedLatest)];
            newInfo{3} = ['Skipped total: '  num2str(navData.skippedFrames)];
            
            set(handles.info, 'String', newInfo);
            
            %set drop down list (stimuli)
            [tmp, C, Z] = size(chstimuli);
            stimList = [];
            for c = 1:C
                stimList{c} = [chstimuli(c).name];
                if c == navData.activeStim
                    stimList{c} = strcat(stimList{c}, '*');
                end
            end
            set(handles.stimList, 'String', stimList);
            set(handles.stimList, 'Value', index);
            
            %set drop down list (layer)
            [tmp, C, Z] = size(chstimuli(index).layers);
            layerList = [];
            
            for c = 1:C
                layerList{c} = [chstimuli(index).layers(c).name];
            
                if c == z
                    layerList{c} = strcat(layerList{c}, '*');
                end
            end
            set(handles.layerList, 'String', layerList);
            set(handles.layerList, 'Value', z);
            
            initBgColorEditBox(app, handles.editBgColor);
            
            % % %disable run button
            % % if screenData.inUse || ~screenData.isInit
            % %     set(handles.run, 'Enable', 'off');
            % % else
            % %     set(handles.run, 'Enable', 'on');
            % % end
            
            %disable "next" button
            if index == length(chstimuli)
                set(app.next, 'Visible', 'Off');
            else
                set(app.next, 'Visible', 'On');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.uitable1.ColumnFormat = {[] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tableGui_OpeningFcn(app, varargin)
            % --- Executes just before tableGui is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to tableGui (see VARARGIN)
            
            % Choose default command line output for tableGui
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes tableGui wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            navData   = getappdata(0, 'navData');
            
            screenRes = get(0, 'ScreenSize'); %[1 1 screenWidth screenHeight]
            
            set(gcf, 'Units' , 'Pixels');
            posdata = get(gcf,'Position');
            height = posdata(4);
            width = posdata(3);
            set(gcf, 'Position', [navData.windowPosition (screenRes(4)-height)/2 width height]);
            
            % Get path to flyfly
            flyflyPath = mfilename('fullpath');
            flyflyPath = erase(flyflyPath, 'tableGui');
            flyflyPath = flyflyPath + "Subfunctions";
            %enable subfunctions
            addpath( flyflyPath)

            app.previewVid.Visible = "on";
            app.previewVid.Enable = "off";
            
            %Save handle to this figure
            hGui = gcf;
            navFcn(handles, 'AddHandle', hGui);
            
            %disp('tableGui started')
            updateFigure(app, handles);
        end

        % Button pushed function: applyBgColour
        function applyBgColour_Callback(app, event)
            % --- Executes on button press in applyBgColour.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to applyBgColour (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            navData    = getappdata(0, 'navData');
            chstimuli  = getappdata(0, 'chstimuli');
            index    = navData.activeStim;
            screenData = getappdata(0, 'screenData');
            
            Screen('FillRect', screenData.wPtr,  chstimuli(index).targetBgColor);
            Screen('FillRect', screenData.wPtr, screenData.triggerRGBoff, screenData.triggerPos); %trigger off
            Screen('Flip', screenData.wPtr);
            screenData.bgColor = chstimuli(index).targetBgColor;
            setappdata(0, 'screenData', screenData);
        end

        % Button pushed function: applyWhiteNoiseBtn
        function applyWhiteNoiseBtn_Callback(app, event)
            % --- Executes on button press in applyWhiteNoiseBtn.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to applyWhiteNoiseBtn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
            
                index    = navData.activeStim;
                z        = navData.marker(index);
            
                data     = chstimuli(index).layers(z).data;
                [R, C] = size(data);
            
                row = str2num(get(handles.chosenRow, 'String'));
            
                variance = str2num(get(handles.gaussianWhiteNoiseVariance, 'String'));
                noise_vector = pseudo_normal(0, variance, C);
            
                data(row, 1:end) = data(row, 1:end) + noise_vector;
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            
                updateFigure(app, handles);
        end

        % Menu selected function: avimovie
        function avimovie_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to avimovie (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            chstimuli  = getappdata(0, 'chstimuli');
            
            index      = navData.activeStim;
            
            if screenData.isInit
            
                [~, C]  = size(chstimuli(index).layers(1).data); %same number of trials in all layers
            
                if get(handles.trialSubset, 'Value') %cut down data set
                    trialSubset = get(handles.trials, 'String');
            
                    if isempty(trialSubset)
                        trialSubset = 1:C;
                    else
                        trialSubset = eval(strrep(trialSubset,'end',num2str(C)));
                        % automatically clip values larger than the number of trials
                        trialSubset = min(C,trialSubset);
                    end
            
                else
                    trialSubset = 1:C;
                end
            
                fps = inputdlg({'Framerate to record in:'}, 'FPS', 1, {num2str(screenData.hz)});
                fps = str2num(fps{1});
            
                fprintf('Creating AVI file...\n');
                imageArray = getStimImages(chstimuli(index), screenData, navData, trialSubset, fps);
            
                [filename, pathname] = uiputfile('*.avi', 'Save stimulus movie to...');
                if isequal(filename,0) || isequal(pathname,0)
                    disp('File NOT saved!');
                else
                    disp('Working...');
                    fn = fullfile(pathname, filename);
                    aviobj = VideoWriter(fn);
                    aviobj.FrameRate = fps;
                    open(aviobj);
                    for k = 1:length(imageArray)
                        currframe = im2frame(imageArray{k});
                        writeVideo(aviobj,currframe);
                    end
                    close(aviobj);
                    fprintf('AVI file saved to %s!\n',fn);
                end
            else
                disp('Screen is not initialized');
            end
        end

        % Button pushed function: clear
        function clear_Callback(app, event)
            % --- Executes on button press in clear.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            screenFcn('Clear');
            
            screenData = getappdata(0, 'screenData');
            screenData.inUse = 0;
            setappdata(0, 'screenData', screenData);
            updateFigure(app, handles);
        end

        % Value changed function: editBgColor
        function editBgColor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editBgColor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editBgColor as text
            %        str2double(get(hObject,'String')) returns contents of editBgColor as a double
            %disp('Edit: this is the callback');
            tbgc = str2num(get(hObject, 'String'));
            
            navData    = getappdata(0, 'navData');
            chstimuli  = getappdata(0, 'chstimuli');
            index    = navData.activeStim;
            % chstimuli(index).beforeBgColor = chstimuli(index).bgColor;
            chstimuli(index).targetBgColor = tbgc;
            setappdata(0, 'chstimuli', chstimuli);
        end

        % Button pushed function: editLayerName
        function editLayerName_Callback(app, event)
            % --- Executes on button press in editLayerName.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editLayerName (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            index    = navData.activeStim;
            
            newName = inputdlg({'Enter new layer name: '}, '', 1, {chstimuli(index).layers(navData.marker(index)).name});
            
            if ~isempty(newName)
            
                chstimuli(index).layers(navData.marker(index)).name = newName{1};
                setappdata(0, 'chstimuli', chstimuli);
            
                updateTable(app, handles);
            end
        end

        % Button pushed function: editName
        function editName_Callback(app, event)
            % --- Executes on button press in editName.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editName (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            index     = navData.activeStim;
            
            newName = inputdlg({'Enter new name: '}, '', 1, {chstimuli(index).name});
            
            if ~isempty(newName)
                chstimuli(index).name = newName{1};
                setappdata(0, 'chstimuli', chstimuli);
            
                updateTable(app, handles);
            end
        end

        % Menu selected function: exit
        function exit_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close(gcf);
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close figure1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: delete(hObject) closes the figure
            
            navFcn(handles, 'Close'); % clean up
            delete(hObject);
        end

        % Button pushed function: generateXYbutton
        function generateXYbutton_Callback(app, event)
            % --- Executes on button press in generateXYbutton.
            % hObject    handle to generateXYbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            	screendata = getappdata(0, 'screenData');
                if screendata.ifi == 0
                    warndlg('Please initialize screen first.');
                else
                    chstimuli = getappdata(0, 'chstimuli');
                    navData   = getappdata(0, 'navData');
                    index    = navData.activeStim;
                    z        = navData.marker(index);
                    data     = chstimuli(index).layers(z).data;
            
                    NUM_TRIALS = size(data, 2);
                    SUBWIDTH = data(1, 1);
                    SUBHEIGHT = data(2, 1);
                    MIDX = data(3, 1);
                    MIDY = data(4, 1);
                    SPEED = data(9, 1);
                    MOVE_DURATION = data(10,1)*screendata.ifi;
                    TARGET_SIZE = 15;
            
                    [data(5, :), data(6, :), data(7, :), data(8, :)] = ...
                        generateXY(NUM_TRIALS, SPEED, MOVE_DURATION, SUBWIDTH, SUBHEIGHT, MIDX, MIDY, TARGET_SIZE);
            
                    chstimuli(index).layers(z).data = data;
                    setappdata(0, 'chstimuli', chstimuli);
            
                    updateFigure(app, handles);
                end
        end

        % Button pushed function: grid
        function grid_Callback(app, event)
            % --- Executes on button press in grid.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to grid (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            screenData = getappdata(0, 'screenData');
            
            if screenData.isInit
                screenFcn('DrawGrid');
                Screen(screenData.wPtr, 'Flip');
            
                screenData.inUse = 0;
                setappdata(0, 'screenData', screenData);
                updateFigure(app, handles);
            end
        end

        % Menu selected function: importStim
        function importStim_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to importStim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            menuFcn('importStim');
            updateFigure(app, handles);
        end

        % Value changed function: impulse
        function impulse_Callback(app, event)
            % --- Executes on button press in impulse.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to impulse (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of impulse
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            index     = navData.activeStim;
            z         = navData.marker(index);
            
            chstimuli(index).layers(z).impulse = get(hObject,'Value');
            
            setappdata(0, 'chstimuli', chstimuli);
        end

        % Menu selected function: imsequence
        function imsequence_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to imsequence (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            chstimuli  = getappdata(0, 'chstimuli');
            
            index      = navData.activeStim;
            
            if screenData.isInit
            
                [~, C]  = size(chstimuli(index).layers(1).data); %same number of trials in all layers
            
                if get(handles.trialSubset, 'Value') %cut down data set
                    trialSubset = get(handles.trials, 'String');
            
                    if isempty(trialSubset)
                        trialSubset = 1:C;
                    else
                        trialSubset = eval(strrep(trialSubset,'end',num2str(C)));
                        % automatically clip values larger than the number of trials
                        trialSubset = min(C,trialSubset);
                    end
            
                else
                    trialSubset = 1:C;
                end
            
                fps = inputdlg({'Framerate to record in:'}, 'FPS', 1, {num2str(screenData.hz)});
                fps = str2num(fps{1});
                disp('Creating image sequence...')
                imageArray = getStimImages(chstimuli(index), screenData, navData, trialSubset, fps);
            
                name = inputdlg({'Name sequence '}, 'Name', 1, {chstimuli(navData.activeStim).name});
                if ~isempty(name) %empty if press on cancel
                    dir_name = uigetdir(cd,'Set target directory');
                    if ischar(dir_name)
                        ID    = datestr(now, 0);
                        ID    = regexprep(ID, ':', '_'); %exchange ':' with '_'
                        for k = 1:length(imageArray)
                            imwrite(imageArray{k}, fullfile(dir_name,sprintf('%s %s_%04.f.png',name{1},ID,k)));
                        end
                        fprintf('Sequence saved to %s!\n',dir_name);
                    else
                        disp('Image sequence NOT saved!');
                    end
                else
                    disp('Image sequence NOT saved!');
                end
            else
                disp('Screen is not initialized');
            end
        end

        % Button pushed function: initScreen
        function initScreen_Callback(app, event)
            % --- Executes on button press in initScreen.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to initScreen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            screenData = getappdata(0, 'screenData');
            
            screenFcn('Init');
            app.previewVid.Enable = 'on';
            app.killScreen.Enable = 'on';
            app.initScreen.Enable = 'off';

            if screenData.useGuvcview && screenData.recording
                path = which('initCam.sh');
                command = "bash " + path + " &"; % links to bash script that starts guvcview with video parameters 
                disp('Trying to Init Cam');
                [~, ~] = system(command, '-echo'); % Runs command in terminal
            end

            updateFigure(app, handles);
        end

        % Button pushed function: killScreen
        function killScreen_Callback(app, event)
            % --- Executes on button press in killScreen.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to killScreen (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            screenData = getappdata(0, 'screenData');
            
            screenFcn('Kill');
            app.previewVid.Enable = 'off';
            app.killScreen.Enable = 'off';
            app.initScreen.Enable = 'on';

            if screenData.useGuvcview && screenData.recording
                command = "pkill guvcview"; % links to bash script that starts guvcview with video parameters 
                disp('Trying to close guvcview');
                [~, ~] = system(command, '-echo'); % Runs command in terminal
            end


            updateFigure(app, handles);
        end

        % Value changed function: layerList
        function layerList_Callback(app, event)
            % --- Executes on selection change in layerList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to layerList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns layerList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from layerList
            
            %get new selection
            layer        = get(hObject,'Value'); %index of chosen stim
            gotoLayer(app, handles, layer)
        end

        % Button pushed function: layerManager
        function layerManager_Callback(app, event)
            % --- Executes on button press in layerManager.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to layerManager (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            layerManager(@app.updateTable, handles);
        end

        % Button pushed function: mseq
        function mseq_Callback(app, event)
            % --- Executes on button press in mseq.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to mseq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            param = inputdlg({'Base value: ','N:', 'Shift:', 'Which sequence:'}, 'm-sequence', 1, {'2','6','1','1'});
            if ~isempty(param)
                baseVal = str2num(param{1});
                p = str2num(param{2});
                shift = str2num(param{3});
                which = str2num(param{4});
                if isnumeric(baseVal) && isnumeric(p) && isnumeric(shift) && isnumeric(which)
                    change = str2num(get(handles.mseqChange, 'String'));
                    sequence = change*mseq(baseVal,p,shift,which);
            
                    chstimuli = getappdata(0, 'chstimuli');
                    navData   = getappdata(0, 'navData');
            
                    index    = navData.activeStim;
                    z        = navData.marker(index);
            
                    data     = chstimuli(index).layers(z).data;
                    chstimuli(index).layers(z).name = ...
                        sprintf('%s (mseq: %d,%d,%d,%d)',chstimuli(index).layers(z).name,baseVal,p,shift,which);
            
                    [R C]  = size(data);
                    row = str2num(get(handles.chosenRow, 'String'));
            
                    start = str2num(get(handles.mseqStart, 'String'));
                    % extend sequence if it is too short
                    sequence = repmat(sequence,ceil(C/length(sequence)),1);
                    if get(handles.useCumsum, 'Value')
                        data(row, 1:end) = start+cumsum(sequence(1:C));
                    else
                        data(row, 1:end) = start+sequence(1:C);
                    end
            
                    chstimuli(index).layers(z).data = data;
                    setappdata(0, 'chstimuli', chstimuli);
            
                    updateFigure(app, handles);
                end
            end
        end

        % Menu selected function: new
        function new_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to new (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            menuFcn('New');
        end

        % Button pushed function: next
        function next_Callback(app, event)
            % --- Executes on button press in next.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to next (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %load data
            navData   = getappdata(0, 'navData');
            index     = navData.activeStim;                         %current stim
            
            gotoStim(app, handles, index+1);
        end

        % Button pushed function: numRunsOk
        function numRunsOk_Callback(app, event)
            % --- Executes on button press in numRunsOk.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to numRunsOk (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index    = navData.activeStim;
            numRuns  = round(abs(str2num(get(handles.numRuns, 'String'))));
            
            if isempty(numRuns)
                numRuns = 1;
            end
            
            numLayers = length(chstimuli(index).layers);
            
            for z = 1:numLayers
            
                data     = chstimuli(index).layers(z).data;
                settings = chstimuli(index).layers(z).settings;
                %complex  = chstimuli(index).layers(z).complex;
                [tmp, C]   = size(data);
            
                if numRuns > C %add more columns
                    for c = 1:(numRuns-C)
                        data(:, C+c)     = data(:, C);
                        settings(:, C+c) = settings(:, C);
                        %complex(:, C+c)  = complex(:, C);
                    end
                elseif numRuns < C && numRuns > 0 %remove columns
                    for c = 1:(C-numRuns)
                        data(:, C-c+1)     = [];
                        settings(:, C-c+1) = [];
                        %complex(:, C+c)    = [];
                    end
                end
            
                chstimuli(index).layers(z).data     = data;
                chstimuli(index).layers(z).settings = settings;
                %chstimuli(index).layers(z).complex  = complex;
            end
            
            setappdata(0, 'chstimuli', chstimuli);
            updateTable(app, handles);
        end

        % Menu selected function: open
        function open_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to open (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            menuFcn('Open');
            updateFigure(app, handles);
        end

        % Button pushed function: previous
        function previous_Callback(app, event)
            % --- Executes on button press in previous.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to previous (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            navData   = getappdata(0, 'navData');    %navigation data
            index     = navData.activeStim;          %index of current gui
            
            gotoStim(app, handles, index - 1)
        end

        % Button pushed function: resetMissed
        function resetMissed_Callback(app, event)
            % --- Executes on button press in resetMissed.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to resetMissed (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            navData = getappdata(0, 'navData');
            
            navData.skippedFrames = 0;
            
            disp('Total number of skipped frames reset');
            
            setappdata(0, 'navData', navData);
            updateTable(app, handles);
        end

        % Button pushed function: rowLin
        function rowLin_Callback(app, event)
            % --- Executes on button press in rowLin.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rowLin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Interpolate from start to stop values linearly
            if get(handles.allRows, 'Value')
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
                index     = navData.activeStim;
                z         = navData.marker(index);
            
                data      = chstimuli(index).layers(z).data;
                [R, tmp, tmp] = size(data);
            
                for r = 1:R
                    rowInterp(app, handles, r, 'LIN');
                end
            else
                row = str2num(get(handles.chosenRow, 'String'));
                rowInterp(app, handles, row, 'LIN');
            end
            updateFigure(app, handles);
        end

        % Button pushed function: rowLog
        function rowLog_Callback(app, event)
            % --- Executes on button press in rowLog.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rowLog (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %Interpolate from start to stop values logarithmicly
            if get(handles.allRows, 'Value')
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
                index     = navData.activeStim;
                z         = navData.marker(index);
            
                data      = chstimuli(index).layers(z).data;
                [R, ~, ~] = size(data);
            
                for r = 1:R
                    rowInterp(app, handles, r, 'LOG');
                end
            else
                row = str2num(get(handles.chosenRow, 'String'));
                rowInterp(app, handles, row, 'LOG');
            end
            updateFigure(app, handles);
        end

        % Button pushed function: rowShuffle_1
        function rowShuffle_Callback(app, event)
            % --- Executes on button press in rowShuffle.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to rowShuffle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if get(handles.allRows, 'Value')
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
                index     = navData.activeStim;
                z         = navData.marker(index);
            
                data      = chstimuli(index).layers(z).data;
                [R, ~, ~] = size(data);
            
                for r = 1:R
                    rowShuffle(app, handles, r);
                end
            else
                row = str2num(get(handles.chosenRow, 'String'));
                rowShuffle(app, handles, row);
            end
            updateFigure(app, handles);
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % --- Executes on button press in run.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            navData    = getappdata(0, 'navData');
            screenData = getappdata(0, 'screenData');
            chstimuli  = getappdata(0, 'chstimuli');
            
            index      = navData.activeStim;
            
            screenData.beforeBgColor = screenData.bgColor;
            
            if ~screenData.isInit %if screen is not init, initialize and close after run
                disp(' ');
                disp('Initilizing screen for single experiment...')
                screenFcn('Init');
                screenData  = getappdata(0, 'screenData');
                closeScreen = 1;
            else
                closeScreen = 0;
            end
            
            %Screen is now in use, update navData and figure
            screenData.inUse = 1;
            
            setappdata(0, 'screenData', screenData);
            
            updateFigure(app, handles);
            
            [~, C]  = size(chstimuli(index).layers(1).data); %same number of trials in all layers
            
            if get(handles.trialSubset, 'Value') %cut down data set
                trialSubset = get(handles.trials, 'String');
            
                if isempty(trialSubset)
                    trialSubset = 1:C;
                else
                    trialSubset = eval(strrep(trialSubset,'end',num2str(C)));
                    % automatically clip values larger than the number of trials
                    trialSubset = min(C,trialSubset);
                end
            
            else
                trialSubset = 1:C;
            end
            
            %
            % --RUN STIMULUS HERE--
            navData    = getappdata(0, 'navData');
            chstimuli  = getappdata(0, 'chstimuli');
            index    = navData.activeStim;
            
            % chstimuli(index).beforeBgColor = chstimuli(index).bgColor;
            % chstimuli(index).bgColor = chstimuli(index).targetBgColor;
            % setappdata(0, 'chstimuli', chstimuli);
            
            screenData = getappdata(0, 'screenData');
            screenData.bgColor = chstimuli(index).targetBgColor;

            usePreview = app.videoDuringStim.Value;
            
            missed = animationLoop(chstimuli(index), screenData, navData, trialSubset, usePreview);

            navData.skippedLatest = missed;
            navData.skippedFrames = navData.skippedFrames + missed;
            
            % screenData.beforeBgColor = screenData.bgColor;
            screenData.inUse   = 0;
            
            setappdata(0, 'screenData', screenData);
            setappdata(0, 'navData',    navData);
            
            updateFigure(app, handles);
            
            if closeScreen
                screenFcn('Kill');
            end
        end

        % Menu selected function: saveAs
        function saveAs_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveAs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            menuFcn('SaveAs');
            updateTable(app, handles);
        end

        % Menu selected function: settings
        function settings_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to settings (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            mainSettings;
        end

        % Button pushed function: shiftColLeft
        function shiftColLeft_Callback(app, event)
            % --- Executes on button press in shiftColLeft.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to shiftColLeft (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            shiftColumn(app, -1,handles);
        end

        % Button pushed function: shiftColRight
        function shiftColRight_Callback(app, event)
            % --- Executes on button press in shiftColRight.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to shiftColRight (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            shiftColumn(app, 1,handles);
        end

        % Value changed function: stimList
        function stimList_Callback(app, event)
            % --- Executes on selection change in stimList.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stimList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns stimList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from stimList
            
            %get new selection
            stim        = get(hObject,'Value'); %index of chosen stim
            gotoStim(app, handles, stim)
        end

        % Button pushed function: stimSettings
        function stimSettings_Callback(app, event)
            % --- Executes on button press in stimSettings.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stimSettings (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            layerSettings;
        end

        % Value changed function: trialSubset
        function trialSubset_Callback(app, event)
            % --- Executes on button press in trialSubset.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to trialSubset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of trialSubset
            
            if get(hObject,'Value') %Use single trial
                set(handles.trials, 'Enable', 'on');
            else
                set(handles.trials, 'Enable', 'off');
            end
        end

        % Cell edit callback: uitable1
        function uitable1_CellEditCallback(app, event)
            % --- Executes when entered data in editable cell(s) in uitable1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uitable1 (see GCBO)
            % eventdata  structure with the following fields (see UITABLE)
            %	Indices: chosenRow and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            
            %Update edited data
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            index     = navData.activeStim;
            z         = navData.marker(index);
            
            newData   = get(handles.uitable1, 'Data');
            chstimuli(index).layers(z).data = newData;
            setappdata(0, 'chstimuli', chstimuli);
        end

        % Cell selection callback: uitable1
        function uitable1_CellSelectionCallback(app, event)
            % --- Executes when selected cell(s) is changed in uitable1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uitable1 (see GCBO)
            % eventdata  structure with the following fields (see UITABLE)
            %	Indices: row and column indices of the cell(s) currently selecteds
            % handles    structure with handles and user data (see GUIDATA)
            
            
            index = eventdata.Indices;
            try
                set(handles.chosenRow, 'String', num2str(index(1)));
                set(handles.chosenCol, 'String', num2str(index(2)));
            end
        end

        % Callback function
        function uitable1_KeyPressFcn(app, event)
            % --- Executes on key press with focus on uitable1 and none of its controls.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uitable1 (see GCBO)
            % eventdata  structure with the following fields (see UITABLE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            
            % Paste data into sequencer table
            if length(eventdata.Modifier) == 1 && strcmp(eventdata.Modifier{:},'control') && ...
                strcmp(eventdata.Key,'v')
                import = importdata('-pastespecial');
            
                chstimuli = getappdata(0, 'chstimuli');
                navData   = getappdata(0, 'navData');
            
                index    = navData.activeStim;
                z        = navData.marker(index);
            
                data     = chstimuli(index).layers(z).data;
            
                selectedRow = str2num(get(handles.chosenRow, 'String'));
                selectedCol = str2num(get(handles.chosenCol, 'String'));
            
                [r c] = size(import);   % size of data in clipboard
                [R C] = size(data);     % size of current data
            
                endRow = selectedRow+r-1;
                endRow = min(R,endRow);
            
                endCol = selectedCol+c-1;
                endCol = min(C,endCol);
            
                for i = selectedRow:endRow
                    for j = selectedCol:endCol
                        value = import(i-selectedRow+1, j-selectedCol+1);
                        if isnumeric(value)
                            data(i,j) = value;
                        end
                    end
                end
            
                chstimuli(index).layers(z).data = data;
                setappdata(0, 'chstimuli', chstimuli);
            
            end
            updateFigure(app, handles);
        end

        % Button pushed function: useValue1
        function useValue1_Callback(app, event)
            % --- Executes on button press in useValue1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to useValue1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            chstimuli = getappdata(0, 'chstimuli');
            navData   = getappdata(0, 'navData');
            
            row = str2num(get(handles.chosenRow, 'String'));
            col = str2num(get(handles.chosenCol, 'String'));
            
            numVal = str2num(get(handles.valNum, 'String'));
            
            index    = navData.activeStim;
            z        = navData.marker(index);
            
            data     = chstimuli(index).layers(z).data;
            
            [tmp, C] = size(data);
            if get(handles.allRows, 'Value')
            
                for c = 1:C
                    if mod(c-col, numVal) == 0
                        data(:,c) = data(:,col);
                    end
                end
            
            else
                for c = 1:C
                    if mod(c-col, numVal) == 0
                        data(row,c) = data(row,col);
                    end
                end
            end
            
            chstimuli(index).layers(z).data  = data;
            setappdata(0, 'chstimuli', chstimuli);
            updateTable(app, handles);
        end

        % Button pushed function: previewVid
        function previewVidButtonPushed(app, event)
            screenData = getappdata(0, 'screenData');
            if screenData.recording == 0
                warning("Please enable video recording to use this feature!")
                return
            end
            % Create video object
            if isfield(screenData, "webcamSettings")
                video = videoinput(screenData.videoAdaptor, 1, ...
                    screenData.webcamSettings.videoFormat);
            else
                video = videoinput(screenData.videoAdaptor, 1);
            end

            if isfield(screenData, "webcamSettings")
                % Apply camera specific settings (if available
                vidSrc = getselectedsource(video);
                wcSettings = screenData.webcamSettings;
                vidSrc.Brightness = wcSettings.Brightness;
                vidSrc.Contrast = wcSettings.Contrast;
                vidSrc.FrameRate = wcSettings.FrameRate;
                vidSrc.HorizontalFlip = wcSettings.HorizontalFlip;
                vidSrc.VerticalFlip = wcSettings.VerticalFlip;
                vidSrc.PowerLineFrequency = wcSettings.PowerLineFrequency;
                vidSrc.Saturation = wcSettings.Saturation;
                vidSrc.Sharpness = wcSettings.Sharpness;
                vidSrc.WhiteBalanceMode = wcSettings.WhiteBalanceMode;
            end 

            preview(video);
            pause('on')
            pause(5)
            pause('off')
            closepreview(video);
            delete(video);
        end

        % Value changed function: videoDuringStim
        function videoDuringStimValueChanged(app, event)
            value = app.videoDuringStim.Value;
            if value == 1
                msgbox("Warning: Previewing the video recording whilst stimuli is playing is known to cause frame drops", "Warning", "warn");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'tableGui';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.file = uimenu(app.figure1);
            app.file.Text = 'File';
            app.file.Tag = 'file';
            app.new = uimenu(app.file);
            app.new.MenuSelectedFcn = createCallbackFcn(app, @new_Callback, true);
            app.new.Accelerator = 'N';
            app.new.Text = 'New';
            app.new.Tag = 'new';
            app.open = uimenu(app.file);
            app.open.MenuSelectedFcn = createCallbackFcn(app, @open_Callback, true);
            app.open.Accelerator = 'O';
            app.open.Text = 'Open';
            app.open.Tag = 'open';
            app.saveAs = uimenu(app.file);
            app.saveAs.MenuSelectedFcn = createCallbackFcn(app, @saveAs_Callback, true);
            app.saveAs.Accelerator = 'S';
            app.saveAs.Text = 'Save as...';
            app.saveAs.Tag = 'saveAs';
            app.importStim = uimenu(app.file);
            app.importStim.MenuSelectedFcn = createCallbackFcn(app, @importStim_Callback, true);
            app.importStim.Separator = 'on';
            app.importStim.Accelerator = 'I';
            app.importStim.Text = 'Import Stimulus';
            app.importStim.Tag = 'importStim';
            app.exit = uimenu(app.file);
            app.exit.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Separator = 'on';
            app.exit.Text = 'Exit';
            app.exit.Tag = 'exit';
            app.tools = uimenu(app.figure1);
            app.tools.Text = 'Tools';
            app.tools.Tag = 'tools';
            app.recordStimulus = uimenu(app.tools);
            app.recordStimulus.Accelerator = 'R';
            app.recordStimulus.Text = 'Record stimulus';
            app.recordStimulus.Tag = 'recordStimulus';
            app.avimovie = uimenu(app.recordStimulus);
            app.avimovie.MenuSelectedFcn = createCallbackFcn(app, @avimovie_Callback, true);
            app.avimovie.Text = 'AVI movie';
            app.avimovie.Tag = 'avimovie';
            app.imsequence = uimenu(app.recordStimulus);
            app.imsequence.MenuSelectedFcn = createCallbackFcn(app, @imsequence_Callback, true);
            app.imsequence.Text = 'Image sequence';
            app.imsequence.Tag = 'imsequence';
            app.settings = uimenu(app.tools);
            app.settings.MenuSelectedFcn = createCallbackFcn(app, @settings_Callback, true);
            app.settings.Separator = 'on';
            app.settings.Text = 'Settings';
            app.settings.Tag = 'settings';
            app.uipanel13 = uipanel(app.figure1);
            app.uipanel13.Tag = 'uipanel13';
            app.previous = uibutton(app.uipanel13, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previous_Callback, true);
            app.previous.Tag = 'previous';
            app.previous.Text = 'Previous';
            app.next = uibutton(app.uipanel13, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @next_Callback, true);
            app.next.Tag = 'next';
            app.next.Text = 'Next';
            app.run = uibutton(app.uipanel13, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Tag = 'run';
            app.run.Text = 'RUN';
            app.initScreen = uibutton(app.uipanel13, 'push');
            app.initScreen.ButtonPushedFcn = createCallbackFcn(app, @initScreen_Callback, true);
            app.initScreen.Tag = 'initScreen';
            app.initScreen.Text = 'Init Screen';
            app.killScreen = uibutton(app.uipanel13, 'push');
            app.killScreen.ButtonPushedFcn = createCallbackFcn(app, @killScreen_Callback, true);
            app.killScreen.Tag = 'killScreen';
            app.killScreen.Enable = 'off';
            app.killScreen.Text = 'Kill Screen';
            app.grid = uibutton(app.uipanel13, 'push');
            app.grid.ButtonPushedFcn = createCallbackFcn(app, @grid_Callback, true);
            app.grid.Tag = 'grid';
            app.grid.Text = 'Grid';
            app.clear = uibutton(app.uipanel13, 'push');
            app.clear.ButtonPushedFcn = createCallbackFcn(app, @clear_Callback, true);
            app.clear.Tag = 'clear';
            app.clear.Text = 'Clear';
            app.stimList = uidropdown(app.uipanel13);
            app.stimList.Items = {'Pop-up Menu'};
            app.stimList.ValueChangedFcn = createCallbackFcn(app, @stimList_Callback, true);
            app.stimList.Tag = 'stimList';
            app.stimList.Value = 'Pop-up Menu';
            app.editBgColor = uieditfield(app.uipanel13, 'text');
            app.editBgColor.ValueChangedFcn = createCallbackFcn(app, @editBgColor_Callback, true);
            app.editBgColor.Tag = 'editBgColor';
            app.editBgColor.Value = '100';
            app.applyBgColour = uibutton(app.uipanel13, 'push');
            app.applyBgColour.ButtonPushedFcn = createCallbackFcn(app, @applyBgColour_Callback, true);
            app.applyBgColour.Tag = 'applyBgColour';
            app.applyBgColour.Text = 'Apply';
            app.text36 = uilabel(app.uipanel13);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Background Colour';
            app.uipanel16 = uipanel(app.figure1);
            app.uipanel16.Tag = 'uipanel16';
            app.info = uilabel(app.uipanel16);
            app.info.Tag = 'info';
            app.info.Text = {'Screen:'; 'Missed frames:'; 'Total:'};
            app.resetMissed = uibutton(app.uipanel16, 'push');
            app.resetMissed.ButtonPushedFcn = createCallbackFcn(app, @resetMissed_Callback, true);
            app.resetMissed.Tag = 'resetMissed';
            app.resetMissed.Text = 'Reset';
            app.text17 = uilabel(app.uipanel16);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Info';
            app.stimName = uilabel(app.uipanel16);
            app.stimName.Tag = 'stimName';
            app.stimName.Text = 'tableGui';
            app.editName = uibutton(app.uipanel16, 'push');
            app.editName.ButtonPushedFcn = createCallbackFcn(app, @editName_Callback, true);
            app.editName.Tag = 'editName';
            app.editName.Text = 'Edit';
            app.layerName = uilabel(app.uipanel16);
            app.layerName.Tag = 'layerName';
            app.layerName.Text = 'subtitle';
            app.layerManager = uibutton(app.uipanel16, 'push');
            app.layerManager.ButtonPushedFcn = createCallbackFcn(app, @layerManager_Callback, true);
            app.layerManager.Tag = 'layerManager';
            app.layerManager.Text = 'Layer manager';
            app.layerList = uidropdown(app.uipanel16);
            app.layerList.Items = {'Pop-up Menu'};
            app.layerList.ValueChangedFcn = createCallbackFcn(app, @layerList_Callback, true);
            app.layerList.Tag = 'layerList';
            app.layerList.Value = 'Pop-up Menu';
            app.editLayerName = uibutton(app.uipanel16, 'push');
            app.editLayerName.ButtonPushedFcn = createCallbackFcn(app, @editLayerName_Callback, true);
            app.editLayerName.Tag = 'editLayerName';
            app.editLayerName.Text = 'Edit';
            app.stimSettings = uibutton(app.uipanel16, 'push');
            app.stimSettings.ButtonPushedFcn = createCallbackFcn(app, @stimSettings_Callback, true);
            app.stimSettings.Tag = 'stimSettings';
            app.stimSettings.Text = 'Layer settings';
            app.impulse = uicheckbox(app.uipanel16);
            app.impulse.ValueChangedFcn = createCallbackFcn(app, @impulse_Callback, true);
            app.impulse.Tag = 'impulse';
            app.impulse.Tooltip = 'If this is checked, movement will be calculated only for the first frame of each trial.';
            app.impulse.Text = 'Impulse';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = {'Start'; 'Stop'};
            app.uitable1.ColumnRearrangeable = 'on';
            app.uitable1.RowName = {'Error'; ''; ''; ''};
            app.uitable1.ColumnEditable = [true true];
            app.uitable1.CellEditCallback = createCallbackFcn(app, @uitable1_CellEditCallback, true);
            app.uitable1.CellSelectionCallback = createCallbackFcn(app, @uitable1_CellSelectionCallback, true);
            app.uitable1.Tag = 'uitable1';
            app.TabGroup = uitabgroup(app.figure1);
            app.StandardtoolsTab = uitab(app.TabGroup);
            app.StandardtoolsTab.Title = 'Standard tools';
            app.uipanel1 = uipanel(app.StandardtoolsTab);
            app.uipanel1.Title = 'Sequencer';
            app.uipanel1.Tag = 'uipanel1';
            app.trials = uieditfield(app.uipanel1, 'text');
            app.trials.Tag = 'trials';
            app.trials.Enable = 'off';
            app.trials.Value = '1:end';
            app.trialSubset = uicheckbox(app.uipanel1);
            app.trialSubset.ValueChangedFcn = createCallbackFcn(app, @trialSubset_Callback, true);
            app.trialSubset.Tag = 'trialSubset';
            app.trialSubset.Text = ' Trial Subset';
            app.numRunsOk = uibutton(app.uipanel1, 'push');
            app.numRunsOk.ButtonPushedFcn = createCallbackFcn(app, @numRunsOk_Callback, true);
            app.numRunsOk.Tag = 'numRunsOk';
            app.numRunsOk.Text = 'OK';
            app.numRuns = uieditfield(app.uipanel1, 'text');
            app.numRuns.Tag = 'numRuns';
            app.numRuns.Value = '2';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Number of Trials:';
            app.uipanel2 = uipanel(app.StandardtoolsTab);
            app.uipanel2.Title = 'Interpolate';
            app.uipanel2.Tag = 'uipanel2';
            app.allRows = uicheckbox(app.uipanel2);
            app.allRows.Tag = 'allRows';
            app.allRows.Text = 'All rows';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Row/Col';
            app.rowLin = uibutton(app.uipanel2, 'push');
            app.rowLin.ButtonPushedFcn = createCallbackFcn(app, @rowLin_Callback, true);
            app.rowLin.Tag = 'rowLin';
            app.rowLin.Text = 'Lin';
            app.shiftColRight = uibutton(app.uipanel2, 'push');
            app.shiftColRight.ButtonPushedFcn = createCallbackFcn(app, @shiftColRight_Callback, true);
            app.shiftColRight.Tag = 'shiftColRight';
            app.shiftColRight.Tooltip = 'Shift column to the right';
            app.shiftColRight.Text = '>>';
            app.shiftColLeft = uibutton(app.uipanel2, 'push');
            app.shiftColLeft.ButtonPushedFcn = createCallbackFcn(app, @shiftColLeft_Callback, true);
            app.shiftColLeft.Tag = 'shiftColLeft';
            app.shiftColLeft.Tooltip = 'Shift column to the left';
            app.shiftColLeft.Text = '<<';
            app.valNum = uieditfield(app.uipanel2, 'text');
            app.valNum.Tag = 'valNum';
            app.valNum.Value = '1';
            app.chosenCol = uieditfield(app.uipanel2, 'text');
            app.chosenCol.Tag = 'chosenCol';
            app.chosenCol.Value = '1';
            app.useValue1 = uibutton(app.uipanel2, 'push');
            app.useValue1.ButtonPushedFcn = createCallbackFcn(app, @useValue1_Callback, true);
            app.useValue1.Tag = 'useValue1';
            app.useValue1.Text = 'Use value';
            app.rowShuffle_1 = uibutton(app.uipanel2, 'push');
            app.rowShuffle_1.ButtonPushedFcn = createCallbackFcn(app, @rowShuffle_Callback, true);
            app.rowShuffle_1.Tag = 'rowShuffle_1';
            app.rowShuffle_1.Text = 'Shuffle';
            app.rowLog = uibutton(app.uipanel2, 'push');
            app.rowLog.ButtonPushedFcn = createCallbackFcn(app, @rowLog_Callback, true);
            app.rowLog.Tag = 'rowLog';
            app.rowLog.Text = 'Log';
            app.chosenRow = uieditfield(app.uipanel2, 'text');
            app.chosenRow.Tag = 'chosenRow';
            app.chosenRow.Value = '1';
            app.PreviewVideoPanel = uipanel(app.StandardtoolsTab);
            app.PreviewVideoPanel.Title = 'Preview Video';
            app.previewVid = uibutton(app.PreviewVideoPanel, 'push');
            app.previewVid.ButtonPushedFcn = createCallbackFcn(app, @previewVidButtonPushed, true);
            app.previewVid.Tag = 'next';
            app.previewVid.Enable = 'off';
            app.previewVid.Text = 'Preview Video';
            app.AdvancedtoolsTab = uitab(app.TabGroup);
            app.AdvancedtoolsTab.Title = 'Advanced tools';
            app.uipanel23 = uipanel(app.AdvancedtoolsTab);
            app.uipanel23.Title = 'Paloma Target';
            app.uipanel23.Tag = 'uipanel23';
            app.generateXYbutton = uibutton(app.uipanel23, 'push');
            app.generateXYbutton.ButtonPushedFcn = createCallbackFcn(app, @generateXYbutton_Callback, true);
            app.generateXYbutton.Tag = 'generateXYbutton';
            app.generateXYbutton.Text = 'Generate XY';
            app.uipanel22 = uipanel(app.AdvancedtoolsTab);
            app.uipanel22.Title = 'Gaussian White Noise';
            app.uipanel22.Tag = 'uipanel22';
            app.text35 = uilabel(app.uipanel22);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Variance';
            app.gaussianWhiteNoiseVariance = uieditfield(app.uipanel22, 'text');
            app.gaussianWhiteNoiseVariance.Tag = 'gaussianWhiteNoiseVariance';
            app.gaussianWhiteNoiseVariance.Value = '0';
            app.applyWhiteNoiseBtn = uibutton(app.uipanel22, 'push');
            app.applyWhiteNoiseBtn.ButtonPushedFcn = createCallbackFcn(app, @applyWhiteNoiseBtn_Callback, true);
            app.applyWhiteNoiseBtn.Tag = 'applyWhiteNoiseBtn';
            app.applyWhiteNoiseBtn.Text = 'APPLY';
            app.uipanel18 = uipanel(app.AdvancedtoolsTab);
            app.uipanel18.Title = 'm-sequence';
            app.uipanel18.Tag = 'uipanel18';
            app.useCumsum = uicheckbox(app.uipanel18);
            app.useCumsum.Tag = 'useCumsum';
            app.useCumsum.Text = ' cumsum';
            app.mseqStart = uieditfield(app.uipanel18, 'text');
            app.mseqStart.Tag = 'mseqStart';
            app.mseqStart.Value = '100';
            app.mseqChange = uieditfield(app.uipanel18, 'text');
            app.mseqChange.Tag = 'mseqChange';
            app.mseqChange.Value = '10';
            app.mseq = uibutton(app.uipanel18, 'push');
            app.mseq.ButtonPushedFcn = createCallbackFcn(app, @mseq_Callback, true);
            app.mseq.Tag = 'mseq';
            app.mseq.Text = 'MSeq';
            app.uipanel18_2 = uipanel(app.AdvancedtoolsTab);
            app.uipanel18_2.Title = 'Enable video preview';
            app.uipanel18_2.Tag = 'uipanel18';
            app.videoDuringStim = uicheckbox(app.uipanel18_2);
            app.videoDuringStim.ValueChangedFcn = createCallbackFcn(app, @videoDuringStimValueChanged, true);
            app.videoDuringStim.Tag = 'useCumsum';
            app.videoDuringStim.Text = 'Preview video during stimuli';
            app.UnneededifusingguvcviewLabel = uilabel(app.uipanel18_2);
            app.UnneededifusingguvcviewLabel.Text = '*Unneeded if using guvcview';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tableGui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)tableGui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end